classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CANPanelUIFigure            matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        PanelTab                    matlab.ui.container.Tab
        StopAlertCheckBox           matlab.ui.control.CheckBox
        CheckAlertCheckBox          matlab.ui.control.CheckBox
        MainBeamAlertCheckBox       matlab.ui.control.CheckBox
        ParkBrakeAlretCheckBox      matlab.ui.control.CheckBox
        DippedBeamAlretCheckBox     matlab.ui.control.CheckBox
        VehicleSpeedEditFieldLabel  matlab.ui.control.Label
        VehicleSpeedEditField       matlab.ui.control.NumericEditField
        TankLevelEditFieldLabel     matlab.ui.control.Label
        TankLevelEditField          matlab.ui.control.NumericEditField
        EngineSpeedEditFieldLabel   matlab.ui.control.Label
        EngineSpeedEditField        matlab.ui.control.NumericEditField
        TelltaleIntensityKnobLabel  matlab.ui.control.Label
        TelltaleIntensityKnob       matlab.ui.control.Knob
        LEDIntensityKnobLabel       matlab.ui.control.Label
        LEDIntensityKnob            matlab.ui.control.Knob
        GaugeilluminationlevelKnobLabel  matlab.ui.control.Label
        GaugeilluminationlevelKnob  matlab.ui.control.Knob
        ConfigTab                   matlab.ui.container.Tab
        OpenPortButton              matlab.ui.control.Button
        ClosePortButton             matlab.ui.control.Button
        UARTStatusEditFieldLabel    matlab.ui.control.Label
        UARTStatusEditField         matlab.ui.control.EditField
        CheckavailableportsButton   matlab.ui.control.Button
        UARTPortDropDownLabel       matlab.ui.control.Label
        UARTPortDropDown            matlab.ui.control.DropDown
        FrequecyEditFieldLabel      matlab.ui.control.Label
        FrequecyEditField           matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        s % Description
        open
        Frame % Description
        freq
        msg=msg_gen() % Description
    end

    methods (Access = private)


    end


    methods (Access = private)

        % Code that executes after component creation
        function Start_init(app)
            app.open=0;
            app.freq=10;
        end

        % Value changed function: VehicleSpeedEditField
        function V_speed(app, event)
            value = app.VehicleSpeedEditField.Value;
            
        end

        % Button pushed function: CheckavailableportsButton
        function portcheck(app, event)
            app.UARTStatusEditField.Value='searching...'
            serialInfo = instrhwinfo('serial');
            app.UARTPortDropDown.Items=serialInfo.AvailableSerialPorts;
            app.UARTStatusEditField.Value='done.'
        end

        % Button pushed function: OpenPortButton
        function openport(app, event)
            app.UARTStatusEditField.Value='try to open...'
            app.s = serial( app.UARTPortDropDown.Value);
            set(app.s,'BaudRate',500000);
            app.s.StopBits=1;
            app.s.Parity='none';
            fopen(app.s);
            app.UARTStatusEditField.Value= app.s.Status;
            app.open=1;
            while app.open
                basicDispSend(app.s,app.msg);
                pause(1/(app.freq));
                            
    CMD=2;
            
    
    ID = uint32(285183855);
    IDtag3=uint8(mod(ID,256));
    IDtag2=uint8( mod(bitshift(ID,-8, 'uint32'), 256) );
    IDtag1=uint8( mod(bitshift(ID,-16, 'uint32'), 256) );
    IDtag0=uint8( mod(bitshift(ID,-24, 'uint32'), 256) );
    data=value;
    DATAtag0=uint8(mod(data,256));
    DATAtag1=uint8(mod(bitshift(data,-8),256));
    DATAtag2=uint8(0);
    DATAtag3=uint8(0);
    DATAtag4=uint8(0);
    DATAtag5=uint8(0);
    DATAtag6=uint8(0);
    DATAtag7=uint8(0);
            
            
    buff=uint8([CMD, IDtag0, IDtag1,IDtag2, IDtag3, 8, DATAtag0 DATAtag1 DATAtag2 DATAtag3 DATAtag4 DATAtag5 DATAtag6 DATAtag7]);
            
            
     fwrite(app.s,buff);
            end
        end

        % Button pushed function: ClosePortButton
        function closeport(app, event)
            app.UARTStatusEditField.Value='try to open...'
            fclose(app.s);
            app.UARTStatusEditField.Value= app.s.Status;
        end

        % Value changed function: StopAlertCheckBox
        function stop_push(app, event)
            value = app.StopAlertCheckBox.Value;
            if value ==1 
                app.msg.StopAlert_cmd=1;
            else
                app.msg.StopAlert_cmd=0;
            end
            
        end

        % Close request function: CANPanelUIFigure
        function close(app, event)
           app.open=0;
            fclose(app.s);
            delete(app);
            
        end

        % Value changed function: FrequecyEditField
        function freq_mod(app, event)
            value = app.FrequecyEditField.Value;
            app.freq=value;
        end

        % Value changed function: CheckAlertCheckBox
        function check_push(app, event)
            value = app.CheckAlertCheckBox.Value;
            if value ==1 
                app.msg.CheckAlert_cmd=1;
            else
                app.msg.CheckAlert_cmd=0;
            end
        end

        % Value changed function: MainBeamAlertCheckBox
        function mainbeam_push(app, event)
            value = app.MainBeamAlertCheckBox.Value;
            if value ==1 
                app.msg.MainBeamAlert_cmd=1;
            else
                app.msg.MainBeamAlert_cmd=0;
            end
        end

        % Value changed function: ParkBrakeAlretCheckBox
        function brake_push(app, event)
            value = app.ParkBrakeAlretCheckBox.Value;
            if value ==1 
                app.msg.ParkBrakeAlert_cmd=1;
            else
                app.msg.ParkBrakeAlert_cmd=0;
            end
        end

        % Value changed function: DippedBeamAlretCheckBox
        function dipped_push(app, event)
            value = app.DippedBeamAlretCheckBox.Value;
            if value ==1 
                app.msg.DippedBeamAlert_cmd=1;
            else
                app.msg.DippedBeamAlert_cmd=0;
            end
        end

        % Value changed function: EngineSpeedEditField
        function engine_push(app, event)
            value = app.EngineSpeedEditField.Value;
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CANPanelUIFigure = uifigure;
            app.CANPanelUIFigure.Name = 'CAN Panel';
            app.CANPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @close, true);
            setAutoResize(app, app.CANPanelUIFigure, true)
            app.TabGroup = uitabgroup(app.CANPanelUIFigure);
            app.PanelTab = uitab(app.TabGroup);
            app.PanelTab.Title = 'Panel';
            app.PanelTab.Units = 'pixels';
            app.StopAlertCheckBox = uicheckbox(app.PanelTab);
            app.StopAlertCheckBox.ValueChangedFcn = createCallbackFcn(app, @stop_push, true);
            app.StopAlertCheckBox.Text = 'Stop Alert ';
            app.CheckAlertCheckBox = uicheckbox(app.PanelTab);
            app.CheckAlertCheckBox.ValueChangedFcn = createCallbackFcn(app, @check_push, true);
            app.CheckAlertCheckBox.Text = 'Check Alert ';
            app.MainBeamAlertCheckBox = uicheckbox(app.PanelTab);
            app.MainBeamAlertCheckBox.ValueChangedFcn = createCallbackFcn(app, @mainbeam_push, true);
            app.MainBeamAlertCheckBox.Text = 'Main Beam Alert';
            app.ParkBrakeAlretCheckBox = uicheckbox(app.PanelTab);
            app.ParkBrakeAlretCheckBox.ValueChangedFcn = createCallbackFcn(app, @brake_push, true);
            app.ParkBrakeAlretCheckBox.Text = 'Park Brake Alret ';
            app.DippedBeamAlretCheckBox = uicheckbox(app.PanelTab);
            app.DippedBeamAlretCheckBox.ValueChangedFcn = createCallbackFcn(app, @dipped_push, true);
            app.DippedBeamAlretCheckBox.Text = 'Dipped Beam Alret ';
            app.VehicleSpeedEditFieldLabel = uilabel(app.PanelTab);
            app.VehicleSpeedEditFieldLabel.Text = 'Vehicle Speed';
            app.VehicleSpeedEditField = uieditfield(app.PanelTab, 'numeric');
            app.VehicleSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @V_speed, true);
            app.TankLevelEditFieldLabel = uilabel(app.PanelTab);
            app.TankLevelEditFieldLabel.Text = 'Tank Level';
            app.TankLevelEditField = uieditfield(app.PanelTab, 'numeric');
            app.EngineSpeedEditFieldLabel = uilabel(app.PanelTab);
            app.EngineSpeedEditFieldLabel.Text = 'Engine Speed';
            app.EngineSpeedEditField = uieditfield(app.PanelTab, 'numeric');
            app.EngineSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @engine_push, true);
            app.TelltaleIntensityKnobLabel = uilabel(app.PanelTab);
            app.TelltaleIntensityKnobLabel.Text = 'Telltale Intensity';
            app.TelltaleIntensityKnob = uiknob(app.PanelTab, 'continuous');
            app.TelltaleIntensityKnob.Limits = [0 16];
            app.TelltaleIntensityKnob.MajorTickLabels = {'Min', '', '', '', '', '', '', '', 'Max'};
            app.LEDIntensityKnobLabel = uilabel(app.PanelTab);
            app.LEDIntensityKnobLabel.Text = 'LED Intensity';
            app.LEDIntensityKnob = uiknob(app.PanelTab, 'continuous');
            app.LEDIntensityKnob.Limits = [0 16];
            app.LEDIntensityKnob.MajorTickLabels = {'Min', '', '', '', '', '', '', '', 'Max'};
            app.GaugeilluminationlevelKnobLabel = uilabel(app.PanelTab);
            app.GaugeilluminationlevelKnobLabel.Text = 'Gauge illumination level';
            app.GaugeilluminationlevelKnob = uiknob(app.PanelTab, 'continuous');
            app.GaugeilluminationlevelKnob.MajorTickLabels = {'Min', '', '', '', '', '', '', '', '', '', 'Max'};
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.ConfigTab.Units = 'pixels';
            app.OpenPortButton = uibutton(app.ConfigTab, 'push');
            app.OpenPortButton.ButtonPushedFcn = createCallbackFcn(app, @openport, true);
            app.OpenPortButton.Text = 'Open Port';
            app.ClosePortButton = uibutton(app.ConfigTab, 'push');
            app.ClosePortButton.ButtonPushedFcn = createCallbackFcn(app, @closeport, true);
            app.ClosePortButton.Text = 'Close Port';
            app.UARTStatusEditFieldLabel = uilabel(app.ConfigTab);
            app.UARTStatusEditFieldLabel.Text = 'UART Status';
            app.UARTStatusEditField = uieditfield(app.ConfigTab, 'text');
            app.UARTStatusEditField.Editable = 'off';
            app.CheckavailableportsButton = uibutton(app.ConfigTab, 'push');
            app.CheckavailableportsButton.ButtonPushedFcn = createCallbackFcn(app, @portcheck, true);
            app.CheckavailableportsButton.Text = 'Check available ports';
            app.UARTPortDropDownLabel = uilabel(app.ConfigTab);
            app.UARTPortDropDownLabel.Text = 'UART Port';
            app.UARTPortDropDown = uidropdown(app.ConfigTab);
            app.UARTPortDropDown.Items = {};
            app.UARTPortDropDown.Value = {};
            app.FrequecyEditFieldLabel = uilabel(app.ConfigTab);
            app.FrequecyEditFieldLabel.Text = 'Frequecy';
            app.FrequecyEditField = uieditfield(app.ConfigTab, 'numeric');
            app.FrequecyEditField.ValueChangedFcn = createCallbackFcn(app, @freq_mod, true);
            app.FrequecyEditField.Value = 10;
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CANPanelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Start_init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CANPanelUIFigure)
        end
    end
end